classdef cbmex_config < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        enable_ch_b                   matlab.ui.control.Button
        disable_ch_b                  matlab.ui.control.Button
        DisabledChannelsListBoxLabel  matlab.ui.control.Label
        DisabledChannelsListBox       matlab.ui.control.ListBox
        freqlineSpinner_2Label        matlab.ui.control.Label
        freqlineSpinner_2             matlab.ui.control.Spinner
        EnabledChannelsListBoxLabel   matlab.ui.control.Label
        EnabledChannelsListBox        matlab.ui.control.ListBox
        notchwidthHzSpinnerLabel      matlab.ui.control.Label
        notchwidthHzSpinner           matlab.ui.control.Spinner
        ConnectionPanel               matlab.ui.container.Panel
        IPAddressEditFieldLabel       matlab.ui.control.Label
        IPAddressEditField            matlab.ui.control.EditField
        ConnectButton                 matlab.ui.control.Button
        connect_ta                    matlab.ui.control.TextArea
        ffftlengthsEditFieldLabel     matlab.ui.control.Label
        ffftlengthsEditField          matlab.ui.control.EditField
        toshowSpinnerLabel            matlab.ui.control.Label
        toshowSpinner                 matlab.ui.control.Spinner
        TabGroup                      matlab.ui.container.TabGroup
        VisualizationTab              matlab.ui.container.Tab
        EditField_3                   matlab.ui.control.NumericEditField
        EditField_4                   matlab.ui.control.NumericEditField
        SelectFrecuencyListBox        matlab.ui.control.ListBox
        DisplayZoomFreqHzEditFieldLabel  matlab.ui.control.Label
        DisplayZoomFreqHzEditField    matlab.ui.control.NumericEditField
        DisplayFreqHzEditFieldLabel   matlab.ui.control.Label
        DisplayFreqHzEditField        matlab.ui.control.NumericEditField
        FiltersTab                    matlab.ui.container.Tab
        CustomFilterCheckBox          matlab.ui.control.CheckBox
        EllipFilterdefinitionButtonGroup  matlab.ui.container.ButtonGroup
        stopbandButton                matlab.ui.control.RadioButton
        orderButton                   matlab.ui.control.RadioButton
        SelectFrecuencyListBox_2      matlab.ui.control.ListBox
        EditField_6                   matlab.ui.control.NumericEditField
        StopfreqHzEditFieldLabel      matlab.ui.control.Label
        StopfreqHzEditField           matlab.ui.control.NumericEditField
        RsEditField_2Label            matlab.ui.control.Label
        RsEditField_2                 matlab.ui.control.NumericEditField
        OrderEditFieldLabel           matlab.ui.control.Label
        OrderEditField                matlab.ui.control.NumericEditField
        RpEditFieldLabel              matlab.ui.control.Label
        RpEditField                   matlab.ui.control.NumericEditField
        EditField_5                   matlab.ui.control.NumericEditField
        BandPassHzEditFieldLabel      matlab.ui.control.Label
        BandPassHzEditField           matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        enable_all_ch_b               matlab.ui.control.Button
        disable_all_ch_b              matlab.ui.control.Button
        SelectbystringButtonGroup     matlab.ui.container.ButtonGroup
        select_d_rb                   matlab.ui.control.RadioButton
        select_e_rb                   matlab.ui.control.RadioButton
        selectbystr_ed                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        parameters = []; % Description
        cx_lfp_fig = []; % Description
        labels2ch = table();
        conect_type = {'Connected to Central','Connected via UDP'};
        conect_instrument = {'Connected to an NSP','Connected to nPlay running on the same PC','Connected to an NSP running on the NSP hardware','Connected to nPlay running on a separate PC'};
    
    end
    
    methods (Access = private)
        
        function move_channels(~,origin,dest)
            if isempty(origin.Value)
                return
            end
            dest.Items=[dest.Items origin.Value];
            origin.Items = origin.Items(cellfun(@(x)~any(strcmp(x,origin.Value)),origin.Items));
        end
        
        function move_all_channels(~,origin,dest)
            if isempty(origin.Items)
                return
            end
            dest.Items=[dest.Items origin.Items];
            origin.Items = {};

            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainfig, current_parameters)
            app.parameters = current_parameters;
            app.cx_lfp_fig = mainfig;
            app.IPAddressEditField.Value = current_parameters.IP_address;
        end

        % Value changed function: DisabledChannelsListBox
        function DisabledChannelsListBoxValueChanged(app, event)
            app.EnabledChannelsListBox.Value = {};
        end

        % Value changed function: EnabledChannelsListBox
        function EnabledChannelsListBoxValueChanged(app, event)
            app.DisabledChannelsListBox.Value = {};
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            IP_address = app.IPAddressEditField.Value;
            try
                [connection, source] = cbmex('open','central-addr',IP_address, 'instance',0);
            catch ME
                cbmex('close');
                app.connect_ta.Value = {ME,'close command send to cbmex'};
                return
            end
            app.connect_ta.Value = {app.conect_type{connection},app.conect_instrument{source+1}};
            
            %esto hacerlo si es necesario
            pause(0.01)
            cbmex('mask',0,1)
            pause(0.01)
            cbmex('trialconfig',1,'noevent');
            pause(0.02)
            [~, x] = cbmex('trialdata',1);
            chs = cell2mat(x(:,1));
            pause(0.01)
            labels = cbmex('chanlabel',chs);
            pause(0.01)
            cbmex('close');
            labels = labels(:,1);
            app.labels2ch = table();
            app.labels2ch.ch = chs;
            app.labels2ch.Properties.RowNames = labels;

            %lleno campos
            
            app.DisabledChannelsListBox.Items = labels;
            selected = labels(cellfun(@(x) ~isempty(regexpi(x,'\w0[12]-\w')),labels));
            if ~isempty(selected)
                app.DisabledChannelsListBox.Value = selected;
            end
            
            app.parameters.IP_address = IP_address;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: enable_ch_b
        function enable_ch_bButtonPushed(app, event)
            app.move_channels(app.DisabledChannelsListBox,app.EnabledChannelsListBox)
        end

        % Button pushed function: disable_ch_b
        function disable_ch_bButtonPushed(app, event)
            app.move_channels(app.EnabledChannelsListBox,app.DisabledChannelsListBox)
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            par = app.parameters;
            chs = app.labels2ch(app.EnabledChannelsListBox.Items,:).ch;
            par.channels = reshape(chs,1,[]) ;
            setappdata(app.cx_lfp_fig,'par',par);
            UIFigureCloseRequest(app)
        end

        % Button pushed function: enable_all_ch_b
        function enable_all_ch_bButtonPushed(app, event)
            app.move_all_channels(app.DisabledChannelsListBox,app.EnabledChannelsListBox)
        end

        % Button pushed function: disable_all_ch_b
        function disable_all_ch_bButtonPushed(app, event)
            app.move_all_channels(app.EnabledChannelsListBox,app.DisabledChannelsListBox)
        end

        % Value changed function: selectbystr_ed
        function selectbystr_edValueChanged(app, event)
            value = app.selectbystr_ed.Value;
            if isempty(value)
                return
            end
            if app.select_d_rb.Value
                obj = app.DisabledChannelsListBox;
            else
                obj = app.EnabledChannelsListBox;
            end
            
            selected = obj.Items(cellfun(@(x) ~isempty(regexpi(x,value)),obj.Items));
            if ~isempty(selected)
                app.DisabledChannelsListBox.Value = selected;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.enable_ch_b = uibutton(app.UIFigure, 'push');
            app.enable_ch_b.ButtonPushedFcn = createCallbackFcn(app, @enable_ch_bButtonPushed, true);
            app.enable_ch_b.Text = '>';
            app.disable_ch_b = uibutton(app.UIFigure, 'push');
            app.disable_ch_b.ButtonPushedFcn = createCallbackFcn(app, @disable_ch_bButtonPushed, true);
            app.disable_ch_b.Text = '<';
            app.DisabledChannelsListBoxLabel = uilabel(app.UIFigure);
            app.DisabledChannelsListBoxLabel.Text = 'Disabled Channels';
            app.DisabledChannelsListBox = uilistbox(app.UIFigure);
            app.DisabledChannelsListBox.Items = {};
            app.DisabledChannelsListBox.Multiselect = 'on';
            app.DisabledChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @DisabledChannelsListBoxValueChanged, true);
            app.DisabledChannelsListBox.Value = {};
            app.freqlineSpinner_2Label = uilabel(app.UIFigure);
            app.freqlineSpinner_2Label.Text = 'freq line';
            app.freqlineSpinner_2 = uispinner(app.UIFigure);
            app.EnabledChannelsListBoxLabel = uilabel(app.UIFigure);
            app.EnabledChannelsListBoxLabel.Text = 'Enabled Channels';
            app.EnabledChannelsListBox = uilistbox(app.UIFigure);
            app.EnabledChannelsListBox.Items = {};
            app.EnabledChannelsListBox.Multiselect = 'on';
            app.EnabledChannelsListBox.ValueChangedFcn = createCallbackFcn(app, @EnabledChannelsListBoxValueChanged, true);
            app.EnabledChannelsListBox.Value = {};
            app.notchwidthHzSpinnerLabel = uilabel(app.UIFigure);
            app.notchwidthHzSpinnerLabel.Text = 'notch width (Hz)';
            app.notchwidthHzSpinner = uispinner(app.UIFigure);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.IPAddressEditFieldLabel = uilabel(app.ConnectionPanel);
            app.IPAddressEditFieldLabel.Text = 'IP Address';
            app.IPAddressEditField = uieditfield(app.ConnectionPanel, 'text');
            app.ConnectButton = uibutton(app.ConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.connect_ta = uitextarea(app.ConnectionPanel);
            app.ffftlengthsEditFieldLabel = uilabel(app.UIFigure);
            app.ffftlengthsEditFieldLabel.Text = 'ffftlength (s)';
            app.ffftlengthsEditField = uieditfield(app.UIFigure, 'text');
            app.toshowSpinnerLabel = uilabel(app.UIFigure);
            app.toshowSpinnerLabel.Text = '# to show';
            app.toshowSpinner = uispinner(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.EditField_3 = uieditfield(app.VisualizationTab, 'numeric');
            app.EditField_4 = uieditfield(app.VisualizationTab, 'numeric');
            app.SelectFrecuencyListBox = uilistbox(app.VisualizationTab);
            app.SelectFrecuencyListBox.Items = {'500 Hz', '1000 Hz', '2000 Hz', '10000 Hz', '30000 Hz'};
            app.SelectFrecuencyListBox.Value = '500 Hz';
            app.DisplayZoomFreqHzEditFieldLabel = uilabel(app.VisualizationTab);
            app.DisplayZoomFreqHzEditFieldLabel.Text = 'Display Zoom Freq (Hz)';
            app.DisplayZoomFreqHzEditField = uieditfield(app.VisualizationTab, 'numeric');
            app.DisplayFreqHzEditFieldLabel = uilabel(app.VisualizationTab);
            app.DisplayFreqHzEditFieldLabel.Text = 'Display Freq (Hz)';
            app.DisplayFreqHzEditField = uieditfield(app.VisualizationTab, 'numeric');
            app.FiltersTab = uitab(app.TabGroup);
            app.FiltersTab.Title = 'Filters';
            app.CustomFilterCheckBox = uicheckbox(app.FiltersTab);
            app.CustomFilterCheckBox.Text = 'Custom Filter';
            app.EllipFilterdefinitionButtonGroup = uibuttongroup(app.FiltersTab);
            app.EllipFilterdefinitionButtonGroup.Title = 'Ellip Filter definition';
            app.stopbandButton = uiradiobutton(app.EllipFilterdefinitionButtonGroup);
            app.stopbandButton.Text = 'stop band';
            app.stopbandButton.Value = true;
            app.orderButton = uiradiobutton(app.EllipFilterdefinitionButtonGroup);
            app.orderButton.Text = 'order';
            app.SelectFrecuencyListBox_2 = uilistbox(app.FiltersTab);
            app.SelectFrecuencyListBox_2.Items = {'500 Hz', '1000 Hz', '2000 Hz', '10000 Hz', '30000 Hz'};
            app.SelectFrecuencyListBox_2.Value = '500 Hz';
            app.EditField_6 = uieditfield(app.FiltersTab, 'numeric');
            app.StopfreqHzEditFieldLabel = uilabel(app.FiltersTab);
            app.StopfreqHzEditFieldLabel.Text = 'Stop freq (Hz)';
            app.StopfreqHzEditField = uieditfield(app.FiltersTab, 'numeric');
            app.RsEditField_2Label = uilabel(app.FiltersTab);
            app.RsEditField_2Label.Text = 'Rs';
            app.RsEditField_2 = uieditfield(app.FiltersTab, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.FiltersTab);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.FiltersTab, 'numeric');
            app.RpEditFieldLabel = uilabel(app.FiltersTab);
            app.RpEditFieldLabel.Text = 'Rp';
            app.RpEditField = uieditfield(app.FiltersTab, 'numeric');
            app.EditField_5 = uieditfield(app.FiltersTab, 'numeric');
            app.BandPassHzEditFieldLabel = uilabel(app.FiltersTab);
            app.BandPassHzEditFieldLabel.Text = 'Band Pass (Hz)';
            app.BandPassHzEditField = uieditfield(app.FiltersTab, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.enable_all_ch_b = uibutton(app.UIFigure, 'push');
            app.enable_all_ch_b.ButtonPushedFcn = createCallbackFcn(app, @enable_all_ch_bButtonPushed, true);
            app.enable_all_ch_b.Text = '>>';
            app.disable_all_ch_b = uibutton(app.UIFigure, 'push');
            app.disable_all_ch_b.ButtonPushedFcn = createCallbackFcn(app, @disable_all_ch_bButtonPushed, true);
            app.disable_all_ch_b.Text = '<<';
            app.SelectbystringButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectbystringButtonGroup.Title = 'Select by string';
            app.select_d_rb = uiradiobutton(app.SelectbystringButtonGroup);
            app.select_d_rb.Text = 'on disabled chs';
            app.select_d_rb.Value = true;
            app.select_e_rb = uiradiobutton(app.SelectbystringButtonGroup);
            app.select_e_rb.Text = 'on enabled chs';
            app.selectbystr_ed = uieditfield(app.SelectbystringButtonGroup, 'text');
            app.selectbystr_ed.ValueChangedFcn = createCallbackFcn(app, @selectbystr_edValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cbmex_config(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end